classdef Mobile_Manipulator_Robot_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MobileManipulatorRobotSimulatorUIFigure  matlab.ui.Figure
        MainGridLayout                matlab.ui.container.GridLayout
        DevelopersTanmaySamakandChinmaySamakLabel  matlab.ui.control.Label
        MobileManipulatorRobotSimulatorLabel  matlab.ui.control.Label
        ControlPanel                  matlab.ui.container.Panel
        e_RMSsqrtfracsum_i1nx_dx2y_dy2nLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        TrajectoryTrackingErrorLabel  matlab.ui.control.Label
        ControlConfigurationLabel     matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        RobotConfigurationLabel       matlab.ui.control.Label
        TrajectoryConfigurationLabel  matlab.ui.control.Label
        TrajectorySetup               matlab.ui.container.TabGroup
        RectangularTab                matlab.ui.container.Tab
        LoopRectangleKnob             matlab.ui.control.Knob
        LoopsLabel_2                  matlab.ui.control.Label
        TimeRectangleKnob             matlab.ui.control.Knob
        TimeLabel_2                   matlab.ui.control.Label
        y0RectangleEditField          matlab.ui.control.NumericEditField
        y_0Label                      matlab.ui.control.Label
        x0RectangleEditField          matlab.ui.control.NumericEditField
        x_0Label                      matlab.ui.control.Label
        betaRectangleEditField        matlab.ui.control.NumericEditField
        betaLabel_2                   matlab.ui.control.Label
        bRectangleEditField           matlab.ui.control.NumericEditField
        bmLabel                       matlab.ui.control.Label
        aRectangleEditField           matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        RectangleButton               matlab.ui.control.Button
        RectangleImage                matlab.ui.control.Image
        EllipticalTab                 matlab.ui.container.Tab
        LoopEllipseKnob               matlab.ui.control.Knob
        LoopsLabel                    matlab.ui.control.Label
        TimeEllipseKnob               matlab.ui.control.Knob
        TimeLabel                     matlab.ui.control.Label
        y0EllipseEditField            matlab.ui.control.NumericEditField
        y_0Label_2                    matlab.ui.control.Label
        x0EllipseEditField            matlab.ui.control.NumericEditField
        x_0Label_2                    matlab.ui.control.Label
        betaLabel                     matlab.ui.control.Label
        betaEllipseEditField          matlab.ui.control.NumericEditField
        bmLabel_2                     matlab.ui.control.Label
        bEllipseEditField             matlab.ui.control.NumericEditField
        aEllipseEditField             matlab.ui.control.NumericEditField
        amLabel_2                     matlab.ui.control.Label
        EllipseButton                 matlab.ui.control.Button
        EllipseImage                  matlab.ui.control.Image
        RobotSetup                    matlab.ui.container.TabGroup
        RobotParametersTab            matlab.ui.container.Tab
        RobotParamsButton             matlab.ui.control.Button
        x_0Label_3                    matlab.ui.control.Label
        y_0Label_3                    matlab.ui.control.Label
        y_0Label_4                    matlab.ui.control.Label
        betaLabel_5                   matlab.ui.control.Label
        W_RobotParam                  matlab.ui.control.NumericEditField
        L_RobotParam                  matlab.ui.control.NumericEditField
        r_RobotParam                  matlab.ui.control.NumericEditField
        w_RobotParam                  matlab.ui.control.NumericEditField
        L1_RobotParam                 matlab.ui.control.NumericEditField
        L2_RobotParam                 matlab.ui.control.NumericEditField
        WLabel                        matlab.ui.control.Label
        LLabel                        matlab.ui.control.Label
        RobotImage                    matlab.ui.control.Image
        ControlSetup                  matlab.ui.container.TabGroup
        CLRRMCTSTab                   matlab.ui.container.Tab
        CLRRMC_TS_DeltaSlider         matlab.ui.control.Slider
        deltaSliderLabel_3            matlab.ui.control.Label
        dottheta_1dottheta_20Label_3  matlab.ui.control.Label
        RR_PI_Description_6           matlab.ui.control.Label
        RR_PI_Heading_6               matlab.ui.control.Label
        CLRRMC_TS_K1                  matlab.ui.control.NumericEditField
        CLRRMC_TS_K2                  matlab.ui.control.NumericEditField
        K_2Label_2                    matlab.ui.control.Label
        K_1Label_2                    matlab.ui.control.Label
        CLRRMC_TS_Button              matlab.ui.control.Button
        CLRRMCCSTab                   matlab.ui.container.Tab
        dottheta_1dottheta_20Label_2  matlab.ui.control.Label
        RR_PI_Description_5           matlab.ui.control.Label
        RR_PI_Heading_5               matlab.ui.control.Label
        CLRRMC_CS_DeltaSlider         matlab.ui.control.Slider
        deltaSliderLabel              matlab.ui.control.Label
        CLRRMC_CS_Tau                 matlab.ui.control.NumericEditField
        betaLabel_4                   matlab.ui.control.Label
        CLRRMC_CS_Button              matlab.ui.control.Button
        RRAPTab                       matlab.ui.container.Tab
        VK_1theta_12K_2theta_22K_3phi2Label  matlab.ui.control.Label
        RR_PI_Description_4           matlab.ui.control.Label
        RR_PI_Heading_4               matlab.ui.control.Label
        betaLabel_3                   matlab.ui.control.Label
        RR_AP_Half_K1                 matlab.ui.control.NumericEditField
        RR_AP_Half_K2                 matlab.ui.control.NumericEditField
        K_2Label                      matlab.ui.control.Label
        K_1Label                      matlab.ui.control.Label
        RR_AP_Half_K3                 matlab.ui.control.NumericEditField
        RR_AP_Button                  matlab.ui.control.Button
        RRATS2Tab                     matlab.ui.container.Tab
        dottheta_10Label              matlab.ui.control.Label
        RR_PI_Description_3           matlab.ui.control.Label
        RR_PI_Heading_3               matlab.ui.control.Label
        RR_ATS2_Button                matlab.ui.control.Button
        RRATS1Tab                     matlab.ui.container.Tab
        dottheta_1dottheta_20Label    matlab.ui.control.Label
        RR_PI_Description_2           matlab.ui.control.Label
        RR_PI_Heading_2               matlab.ui.control.Label
        RR_ATS1_Button                matlab.ui.control.Button
        RRPITab                       matlab.ui.container.Tab
        RR_PI_Description             matlab.ui.control.Label
        RR_PI_Heading                 matlab.ui.control.Label
        RR_PI_Button                  matlab.ui.control.Button
        VisualizationPanel            matlab.ui.container.Panel
        VisualizationGridLayout       matlab.ui.container.GridLayout
        RMSE                          matlab.ui.control.NumericEditField
        RMSELabel                     matlab.ui.control.Label
        RobotViz                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Simulation Properties
        StopSimulation = false; % Stop/interrupt simulation
        % Trajectory Properties
        EllipticalTrajectory = false; % Choose elliptical trajectory profile
        RectangularTrajectory = false; % Choose rectangular trajectory profile
        % Robot Control Properties
        RR_PI = false; % Choose redundancy resolution using pseudoinverse method
        RR_ATS_1 = false; % Choose redundancy resolution using augmented task-space method 1 (theta1_dot + theta2_dot = 0)
        RR_ATS_2 = false; % Choose redundancy resolution using augmented task-space method 2 (theta1_dot = 0)
        RR_AP = false; % Choose redundancy resolution using artificial potential method
        CLRRMC_CS = false; % Choose closed-loop resolved-rate motion control in configuration space
        CLRRMC_TS = false; % Choose closed-loop resolved-rate motion control in task space
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.StopSimulation = false;
            
            % Robot Parameters
            L = app.L_RobotParam.Value; % Lenght of mobile base
            W = app.W_RobotParam.Value; % Width of mobile base
            r = app.r_RobotParam.Value; % Wheel radius of mobile base
            w = app.w_RobotParam.Value; % Wheel width of mobile base
            L1 = app.L1_RobotParam.Value; % Link 1 length of manipulator
            L2 = app.L2_RobotParam.Value; % Link 2 length of manipulator
            robot_params = [L,W,r,w,L1,L2];
                        
            if app.EllipticalTrajectory
                % Elliptical path
                x0_ellipse = app.x0EllipseEditField.Value; % Center x-offset
                y0_ellipse = app.y0EllipseEditField.Value; % Center y-offset
                a_ellipse = app.aEllipseEditField.Value; % Semi-major axis
                b_ellipse = app.bEllipseEditField.Value; % Semi-minor axis
                beta_ellipse = deg2rad(app.betaEllipseEditField.Value); % Orientation of major axis
                traversals_ellipse = app.LoopEllipseKnob.Value; % Number of traversals
                alpha_ellipse = deg2rad(linspace(0, traversals_ellipse*359, traversals_ellipse*360));
                x_elips = a_ellipse*cos(alpha_ellipse); % X coordinates for ellipse
                y_elips = b_ellipse*sin(alpha_ellipse); % Y coordinates for ellipse
                x_ellipse = x0_ellipse + x_elips*cos(beta_ellipse) - y_elips*sin(beta_ellipse); % X coordinates for translated & rotated ellipse
                y_ellipse = y0_ellipse + x_elips*sin(beta_ellipse) + y_elips*cos(beta_ellipse); % Y coordinates for translated & rotated ellipse

                % Elliptical trajectory
                cycle_time_ellipse = app.TimeEllipseKnob.Value; % Cycle time
                alpha_dot_ellipse = deg2rad(360/cycle_time_ellipse); % Phaser velocity
                i = 1;
                for alpha_ellipse=deg2rad(0:traversals_ellipse*360-1)
                    x_dot_elips = -a_ellipse.*sin(alpha_ellipse).*alpha_dot_ellipse; % X component of velocity vector
                    y_dot_elips = b_ellipse.*cos(alpha_ellipse).*alpha_dot_ellipse; % Y component of velocity vector
                    x_dot_ellipse = x_dot_elips*cos(beta_ellipse) - y_dot_elips*sin(beta_ellipse); % X component of rotated velocity vector
                    y_dot_ellipse = x_dot_elips*sin(beta_ellipse) + y_dot_elips*cos(beta_ellipse); % Y component of rotated velocity vector
                    X_dot_ellipse(:,i) = [x_dot_ellipse; y_dot_ellipse];
                    i = i+1;
                end

                if app.RR_PI
                    % Redundancy Resolution Using Pseudoinverse Method
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse))];
                    J = [];
                    J_inv = [];
                    q_dot_ellipse = [];
                    q_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_ellipse(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_ellipse*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = pinv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_ellipse(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i)+(q_dot_ellipse(:,i).*(cycle_time_ellipse/360));
                        % Store end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        % Plot
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                elseif app.RR_ATS_1
                    % Redundancy Resolution Using Augmented Task-Space Method (Theta_1_Dot + Theta_2_Dot = 0)
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse)); zeros(1,length(X_dot_ellipse))];
                    J = [];
                    J_inv = [];
                    q_dot_ellipse = [];
                    q_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_ellipse(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_ellipse*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_ellipse(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i)+(q_dot_ellipse(:,i).*(cycle_time_ellipse/360));
                        % Store end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                elseif app.RR_ATS_2
                    % Redundancy Resolution Using Augmented Task-Space Method (Theta_1_Dot = 0)
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse)); zeros(1,length(X_dot_ellipse))];
                    J = [];
                    J_inv = [];
                    q_dot_ellipse = [];
                    q_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_ellipse(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_ellipse*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_ellipse(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i)+(q_dot_ellipse(:,i).*(cycle_time_ellipse/360));
                        % Store end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                elseif app.RR_AP
                    % Redundancy Resolution Using Artificial Potential Method
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse))];
                    J = [];
                    J_inv = [];
                    q_dot_ellipse = [];
                    q_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_ellipse(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_ellipse*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = pinv(J(:,:,i));
                        % Artificial Potential
                        neg_grad_V = [0; 0; -2*app.RR_AP_Half_K3.Value*q_ellipse(3,i); -2*app.RR_AP_Half_K1.Value*q_ellipse(4,i); -2*app.RR_AP_Half_K2.Value*q_ellipse(5,i)];
                        % Compute Joint Velocities
                        q_dot_ellipse(:,i) = (J_inv(:,:,i) * X_dot(:,i)) + ((eye(5)-(J_inv(:,:,i)*J(:,:,i)))*neg_grad_V);
                        % Compute Joint Angles using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i)+(q_dot_ellipse(:,i).*(cycle_time_ellipse/360));
                        % Store end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                elseif app.CLRRMC_CS
                    % Configuration-Space Resolved-Rate Motion Control
                    % Desired configuration-space variables for elliptical trajectory
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse)); zeros(1,length(X_dot_ellipse))];
                    J = [];
                    J_inv = [];
                    q_dot_ellipse = [];
                    q_d_ellipse = [];
                    X_d_ellipse = [];
                    Y_d_ellipse = [];
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_d_ellipse(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_ellipse*360
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i))-robot_params(6)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i))-robot_params(6)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i))+robot_params(6)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i))+robot_params(6)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_d_ellipse(3,i));
                        J42 = cos(q_d_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_ellipse(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_d_ellipse(:,i+1) = q_d_ellipse(:,i)+(q_dot_ellipse(:,i).*(cycle_time_ellipse/360));
                        % Store end-effector positions using FK
                        X_d_ellipse(i) = q_d_ellipse(1,i) + robot_params(5)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i)) + robot_params(6)*cos(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                        Y_d_ellipse(i) = q_d_ellipse(2,i) + robot_params(5)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i)) + robot_params(6)*sin(q_d_ellipse(3,i)+q_d_ellipse(4,i)+q_d_ellipse(5,i));
                    end
                    % Follow the elliptical trajectory
                    q_d_ellipse = q_d_ellipse(:,1:end-1); % Remove last (unwanted) state update
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse)); zeros(1,length(X_dot_ellipse))];
                    J = [];
                    q_dot_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    tau = app.CLRRMC_CS_Tau.Value; % Error dynamics time constant
                    q_disturb_ellipse = 1+app.CLRRMC_CS_DeltaSlider.Value; % Disturbance in initial conditions
                    q_ellipse(:,1) = q_d_ellipse(:,1)*q_disturb_ellipse; % Initial configuration (multiplied by a disturbance)
                    for i = 1:length(q_d_ellipse)
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Configuration-space closed-loop control
                        q_dot_ellipse(:,i) = inv(J(:,:,i))*X_dot(:,i)  + diag(ones(1,5)*1/tau)*(q_d_ellipse(:,i)-q_ellipse(:,i));
                        % Compute configuration variables using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i) + q_dot_ellipse(:,i)*(cycle_time_ellipse/360);
                        % Store end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                elseif app.CLRRMC_TS
                    %% Task-Space Resolved-Rate Motion Control
                    X_dot = [X_dot_ellipse; ones(1,length(X_dot_ellipse))*alpha_dot_ellipse; zeros(1,length(X_dot_ellipse)); zeros(1,length(X_dot_ellipse))];
                    J = [];
                    q_ellipse = [];
                    q_dot_ellipse = [];
                    X_E_ellipse = [];
                    Y_E_ellipse = [];
                    K1 = app.CLRRMC_TS_K1.Value; % Error dynamics negative pole 1
                    K2 = app.CLRRMC_TS_K2.Value; % Error dynamics negative pole 2
                    % Initial configuration
                    X = x_ellipse(1)-robot_params(5);
                    Y = y_ellipse(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_ellipse(1)-X)^2 + (y_ellipse(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_ellipse(1)-Y,x_ellipse(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_disturb_ellipse = 1+app.CLRRMC_TS_DeltaSlider.Value; % Disturbance in initial conditions
                    q_ellipse(:,1)=[X;Y;phi;theta1;theta2]*q_disturb_ellipse; % Initial configuration (multiplied by a disturbance)
                    for i = 1:length(x_ellipse)
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J14 = -robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i))-robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J15 = -robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J24 = robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i))+robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J25 = robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_ellipse(3,i));
                        J42 = cos(q_ellipse(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Actual end-effector positions using FK
                        X_E_ellipse(i) = q_ellipse(1,i) + robot_params(5)*cos(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*cos(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        Y_E_ellipse(i) = q_ellipse(2,i) + robot_params(5)*sin(q_ellipse(3,i)+q_ellipse(4,i)) + robot_params(6)*sin(q_ellipse(3,i)+q_ellipse(4,i)+q_ellipse(5,i));
                        % Task-space closed-loop control
                        q_dot_ellipse(:,i) = inv(J(:,:,i))*(X_dot(:,i) + diag([K1,K2,0,0,0])*[x_ellipse(i)-X_E_ellipse(i);y_ellipse(i)-Y_E_ellipse(i);0;0;0]);
                        % Compute configuration variables using Euler Forward
                        q_ellipse(:,i+1) = q_ellipse(:,i) + q_dot_ellipse(:,i)*(cycle_time_ellipse/360);
                        plot(app.RobotViz,x_ellipse(1:360),y_ellipse(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_ellipse,Y_E_ellipse,'Color',[236,106,49]/255)
                        PlotRobot(app,q_ellipse(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_ellipse = sqrt(sum((x_ellipse(1:i)-X_E_ellipse).^2+(y_ellipse(1:i)-Y_E_ellipse).^2)/length(X_E_ellipse));
                        app.RMSE.Value = RMSE_ellipse;
                    end
                end
            end
            
            if app.RectangularTrajectory
                % Rectangular path
                x0_rectangle = app.x0RectangleEditField.Value; % Center x-offset
                y0_rectangle = app.y0RectangleEditField.Value; % Center y-offset
                a_rectangle = app.aRectangleEditField.Value/2; % Semi-major side length
                b_rectangle = app.bRectangleEditField.Value/2; % Semi-minor side length
                beta_rectangle = deg2rad(app.betaRectangleEditField.Value); % Orientation of major side
                traversals_rectangle = app.LoopRectangleKnob.Value; % Number of traversals
                alpha_rectangle = deg2rad(linspace(0, traversals_rectangle*359, traversals_rectangle*360));
                x_rect = a_rectangle.*(abs(cos(alpha_rectangle)).*cos(alpha_rectangle) - abs(sin(alpha_rectangle)).*sin(alpha_rectangle)); % X coordinates for rectangle
                y_rect = b_rectangle.*(abs(cos(alpha_rectangle)).*cos(alpha_rectangle) + abs(sin(alpha_rectangle)).*sin(alpha_rectangle)); % Y coordinates for rectangle
                x_rectangle = x0_rectangle + x_rect*cos(beta_rectangle) - y_rect*sin(beta_rectangle); % X coordinates for translated & rotated rectangle
                y_rectangle = y0_rectangle + x_rect*sin(beta_rectangle) + y_rect*cos(beta_rectangle); % Y coordinates for translated & rotated rectangle

                % Rectangular trajectory
                cycle_time_rectangle = app.TimeRectangleKnob.Value; % Cycle time
                alpha_dot_rectangle = deg2rad(360/cycle_time_rectangle); % Phaser velocity
                i = 1;
                for alpha_rectangle=deg2rad(0:traversals_rectangle*360-1)
                    x_dot_rect = a_rectangle*((-sin(alpha_rectangle).*cos(alpha_rectangle)./abs(cos(alpha_rectangle))).*cos(alpha_rectangle)*alpha_dot_rectangle + abs(cos(alpha_rectangle)).*(-sin(alpha_rectangle)*alpha_dot_rectangle) - ...
                                 (sin(alpha_rectangle).*cos(alpha_rectangle)./abs(sin(alpha_rectangle))).*sin(alpha_rectangle)*alpha_dot_rectangle - abs(sin(alpha_rectangle)).*(cos(alpha_rectangle)*alpha_dot_rectangle)); % X component of velocity vector
                    y_dot_rect = a_rectangle*((-sin(alpha_rectangle).*cos(alpha_rectangle)./abs(cos(alpha_rectangle))).*cos(alpha_rectangle)*alpha_dot_rectangle + abs(cos(alpha_rectangle)).*(-sin(alpha_rectangle)*alpha_dot_rectangle) + ...
                                 (sin(alpha_rectangle).*cos(alpha_rectangle)./abs(sin(alpha_rectangle))).*sin(alpha_rectangle)*alpha_dot_rectangle + abs(sin(alpha_rectangle)).*(cos(alpha_rectangle)*alpha_dot_rectangle)); % Y component of velocity vector
                    x_dot_rectangle = x_dot_rect*cos(beta_rectangle) - y_dot_rect*sin(beta_rectangle); % X component of rotated velocity vector
                    y_dot_rectangle = x_dot_rect*sin(beta_rectangle) + y_dot_rect*cos(beta_rectangle); % Y component of rotated velocity vector
                    X_dot_rectangle(:,i) = [x_dot_rectangle; y_dot_rectangle];
                    i = i+1;
                end
                X_dot_rectangle(isnan(X_dot_rectangle)) = 0; % Replace NaN elements with zero

                if app.RR_PI
                    % Redundancy Resolution Using Pseudoinverse Method
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle))];
                    J = [];
                    J_inv = [];
                    q_dot_rectangle = [];
                    q_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_rectangle(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_rectangle*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = pinv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_rectangle(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i)+(q_dot_rectangle(:,i).*(cycle_time_rectangle/360));
                        % Store end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                elseif app.RR_ATS_1
                    % Redundancy Resolution Using Augmented Task-Space Method (Theta_1_Dot + Theta_2_Dot = 0)
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle)); zeros(1,length(X_dot_rectangle))];
                    J = [];
                    J_inv = [];
                    q_dot_rectangle = [];
                    q_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_rectangle(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_rectangle*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_rectangle(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i)+(q_dot_rectangle(:,i).*(cycle_time_rectangle/360));
                        % Store end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                elseif app.RR_ATS_2
                    % Redundancy Resolution Using Augmented Task-Space Method (Theta_1_Dot = 0)
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle)); zeros(1,length(X_dot_rectangle))];
                    J = [];
                    J_inv = [];
                    q_dot_rectangle = [];
                    q_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_rectangle(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_rectangle*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_rectangle(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i)+(q_dot_rectangle(:,i).*(cycle_time_rectangle/360));
                        % Store end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                elseif app.RR_AP
                    % Redundancy Resolution Using Artificial Potential Method
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle))];
                    J = [];
                    J_inv = [];
                    q_dot_rectangle = [];
                    q_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_rectangle(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_rectangle*360
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = pinv(J(:,:,i));
                        % Artificial Potential
                        neg_grad_V = [0; 0; -2*app.RR_AP_Half_K3.Value*q_rectangle(3,i); -2*app.RR_AP_Half_K1.Value*q_rectangle(4,i); -2*app.RR_AP_Half_K2.Value*q_rectangle(5,i)];
                        % Compute Joint Velocities
                        q_dot_rectangle(:,i) = (J_inv(:,:,i) * X_dot(:,i)) + ((eye(5)-(J_inv(:,:,i)*J(:,:,i)))*neg_grad_V);
                        % Compute Joint Angles using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i)+(q_dot_rectangle(:,i).*(cycle_time_rectangle/360));
                        % Store end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                elseif app.CLRRMC_CS
                    % Configuration-Space Resolved-Rate Motion Control
                    % Desired configuration-space variables for rectangular trajectory
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle)); zeros(1,length(X_dot_rectangle))];
                    J = [];
                    J_inv = [];
                    q_dot_rectangle = [];
                    q_d_rectangle = [];
                    X_d_rectangle = [];
                    Y_d_rectangle = [];
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_d_rectangle(:,1)=[X;Y;phi;theta1;theta2];
                    for i=1:traversals_rectangle*360
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i))-robot_params(6)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i))-robot_params(6)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i))+robot_params(6)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i))+robot_params(6)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_d_rectangle(3,i));
                        J42 = cos(q_d_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Compute Jacobian Pseudoinverse
                        J_inv(:,:,i) = inv(J(:,:,i));
                        % Compute Joint Velocities
                        q_dot_rectangle(:,i) = J_inv(:,:,i) * X_dot(:,i);
                        % Compute Joint Angles using Euler Forward
                        q_d_rectangle(:,i+1) = q_d_rectangle(:,i)+(q_dot_rectangle(:,i).*(cycle_time_rectangle/360));
                        % Store end-effector positions using FK
                        X_d_rectangle(i) = q_d_rectangle(1,i) + robot_params(5)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i)) + robot_params(6)*cos(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                        Y_d_rectangle(i) = q_d_rectangle(2,i) + robot_params(5)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i)) + robot_params(6)*sin(q_d_rectangle(3,i)+q_d_rectangle(4,i)+q_d_rectangle(5,i));
                    end
                    % Follow the rectangular trajectory
                    q_d_rectangle = q_d_rectangle(:,1:end-1); % Remove last (unwanted) state update
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle)); zeros(1,length(X_dot_rectangle))];
                    J = [];
                    q_dot_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    tau = app.CLRRMC_CS_Tau.Value; % Error dynamics time constant
                    q_disturb_rectangle = 1+app.CLRRMC_CS_DeltaSlider.Value; % Disturbance in initial conditions
                    q_rectangle(:,1) = q_d_rectangle(:,1)*q_disturb_rectangle; % Initial configuration (multiplied by a disturbance)
                    for i = 1:length(q_d_rectangle)
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Configuration-space closed-loop control
                        q_dot_rectangle(:,i) = inv(J(:,:,i))*X_dot(:,i)  + diag(ones(1,5)*1/tau)*(q_d_rectangle(:,i)-q_rectangle(:,i));
                        % Compute configuration variables using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i) + q_dot_rectangle(:,i)*(cycle_time_rectangle/360);
                        % Store end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                elseif app.CLRRMC_TS
                    % Task-Space Resolved-Rate Motion Control
                    X_dot = [X_dot_rectangle; ones(1,length(X_dot_rectangle))*alpha_dot_rectangle; zeros(1,length(X_dot_rectangle)); zeros(1,length(X_dot_rectangle))];
                    J = [];
                    q_rectangle = [];
                    q_dot_rectangle = [];
                    X_E_rectangle = [];
                    Y_E_rectangle = [];
                    K1 = app.CLRRMC_TS_K1.Value; % Error dynamics negative pole 1
                    K2 = app.CLRRMC_TS_K2.Value; % Error dynamics negative pole 2
                    % Initial configuration
                    X = x_rectangle(1)-robot_params(5);
                    Y = y_rectangle(1)-robot_params(6);
                    phi = deg2rad(0);
                    theta2 = real(acos(((x_rectangle(1)-X)^2 + (y_rectangle(1)-Y)^2 - robot_params(5)^2 - robot_params(6)^2)./(2*robot_params(5)*robot_params(6))));
                    theta1 = atan2(y_rectangle(1)-Y,x_rectangle(1)-X) - atan2(robot_params(6).*sin(theta2),(robot_params(5) + robot_params(6).*cos(theta2))) - phi;
                    q_disturb_rectangle = 1+app.CLRRMC_TS_DeltaSlider.Value; % Disturbance in initial conditions
                    q_rectangle(:,1)=[X;Y;phi;theta1;theta2]*q_disturb_rectangle; % Initial configuration (multiplied by a disturbance)
                    for i = 1:length(x_rectangle)
                        % Interrupt Simulation if Stop Button is Pressed
                        pause(0.1)
                        if app.StopSimulation
                            break
                        end
                        % Compute Jacobian
                        J11 = 1;
                        J12 = 0;
                        J13 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J14 = -robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i))-robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J15 = -robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J21 = 0;
                        J22 = 1;
                        J23 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J24 = robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i))+robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J25 = robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        J31 = 0;
                        J32 = 0;
                        J33 = 1;
                        J34 = 1;
                        J35 = 1;
                        J41 = -sin(q_rectangle(3,i));
                        J42 = cos(q_rectangle(3,i));
                        J43 = 0;
                        J44 = 0;
                        J45 = 0;
                        J51 = 0;
                        J52 = 0;
                        J53 = 0;
                        J54 = 1;
                        J55 = 1;
                        J(:,:,i) = [J11 J12 J13 J14 J15;
                                    J21 J22 J23 J24 J25;
                                    J31 J32 J33 J34 J35;
                                    J41 J42 J43 J44 J45;
                                    J51 J52 J53 J54 J55];
                        % Actual end-effector positions using FK
                        X_E_rectangle(i) = q_rectangle(1,i) + robot_params(5)*cos(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*cos(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        Y_E_rectangle(i) = q_rectangle(2,i) + robot_params(5)*sin(q_rectangle(3,i)+q_rectangle(4,i)) + robot_params(6)*sin(q_rectangle(3,i)+q_rectangle(4,i)+q_rectangle(5,i));
                        % Task-space closed-loop control
                        q_dot_rectangle(:,i) = inv(J(:,:,i))*(X_dot(:,i) + diag([K1,K2,0,0,0])*[x_rectangle(i)-X_E_rectangle(i);y_rectangle(i)-Y_E_rectangle(i);0;0;0]);
                        % Compute configuration variables using Euler Forward
                        q_rectangle(:,i+1) = q_rectangle(:,i) + q_dot_rectangle(:,i)*(cycle_time_rectangle/360);
                        plot(app.RobotViz,x_rectangle(1:360),y_rectangle(1:360),'--','Color',[50,50,50]/255)
                        hold(app.RobotViz,'on')
                        plot(app.RobotViz,X_E_rectangle,Y_E_rectangle,'Color',[236,106,49]/255)
                        PlotRobot(app,q_rectangle(:,i),robot_params)
                        legend(app.RobotViz,"Desired Trajectory","Actual Trajectory")
                        hold(app.RobotViz,'off')
                        hold(app.RobotViz,'off')
                        % Trajectory Tracking Error
                        RMSE_rectangle = sqrt(sum((x_rectangle(1:i)-X_E_rectangle).^2+(y_rectangle(1:i)-Y_E_rectangle).^2)/length(X_E_rectangle));
                        app.RMSE.Value = RMSE_rectangle;
                    end
                end
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.StopSimulation = true;
        end

        % Button pushed function: EllipseButton
        function EllipseButtonPushed(app, event)
            app.RectangularTrajectory = false;
            app.EllipticalTrajectory = true;
        end

        % Button pushed function: RectangleButton
        function RectangleButtonPushed(app, event)
            app.EllipticalTrajectory = false;
            app.RectangularTrajectory = true;
        end

        % Button pushed function: RR_PI_Button
        function RR_PI_ButtonPushed(app, event)
            app.RR_ATS_1 = false;
            app.RR_ATS_2 = false;
            app.RR_AP = false;
            app.CLRRMC_CS = false;
            app.CLRRMC_TS = false;
            app.RR_PI = true;
        end

        % Button pushed function: RR_ATS1_Button
        function RR_ATS1_ButtonPushed(app, event)
            app.RR_PI = false;
            app.RR_ATS_2 = false;
            app.RR_AP = false;
            app.CLRRMC_CS = false;
            app.CLRRMC_TS = false;
            app.RR_ATS_1 = true;
        end

        % Button pushed function: RR_ATS2_Button
        function RR_ATS2_ButtonPushed(app, event)
            app.RR_PI = false;
            app.RR_ATS_1 = false;
            app.RR_AP = false;
            app.CLRRMC_CS = false;
            app.CLRRMC_TS = false;
            app.RR_ATS_2 = true;
        end

        % Button pushed function: RR_AP_Button
        function RR_AP_ButtonPushed(app, event)
            app.RR_PI = false;
            app.RR_ATS_1 = false;
            app.RR_ATS_2 = false;
            app.CLRRMC_CS = false;
            app.CLRRMC_TS = false;
            app.RR_AP = true;
        end

        % Button pushed function: CLRRMC_CS_Button
        function CLRRMC_CS_ButtonPushed(app, event)
            app.RR_PI = false;
            app.RR_ATS_1 = false;
            app.RR_ATS_2 = false;
            app.RR_AP = false;
            app.CLRRMC_TS = false;
            app.CLRRMC_CS = true;
        end

        % Button pushed function: CLRRMC_TS_Button
        function CLRRMC_TS_ButtonPushed(app, event)
            app.RR_PI = false;
            app.RR_ATS_1 = false;
            app.RR_ATS_2 = false;
            app.RR_AP = false;
            app.CLRRMC_CS = false;
            app.CLRRMC_TS = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MobileManipulatorRobotSimulatorUIFigure = uifigure('Visible', 'off');
            app.MobileManipulatorRobotSimulatorUIFigure.Name = 'Mobile Manipulator Robot Simulator';
            app.MainGridLayout = uigridlayout(app.MobileManipulatorRobotSimulatorUIFigure);
            app.VisualizationPanel = uipanel(app.MainGridLayout);
            app.VisualizationGridLayout = uigridlayout(app.VisualizationPanel);
            app.RobotViz = uiaxes(app.VisualizationGridLayout);
            title(app.RobotViz, 'Robot Visualization')
            xlabel(app.RobotViz, 'X (m)')
            ylabel(app.RobotViz, 'Y (m)')
            zlabel(app.RobotViz, 'Z (m)')
            app.RobotViz.XLim = [-5 5];
            app.RobotViz.YLim = [-5 5];
            app.RobotViz.ZLim = [-5 5];
            app.RMSELabel = uilabel(app.VisualizationGridLayout);
            app.RMSELabel.Text = 'RMSE (m)';
            app.RMSE = uieditfield(app.VisualizationGridLayout, 'numeric');
            app.RMSE.Editable = 'off';
            app.ControlPanel = uipanel(app.MainGridLayout);
            app.ControlSetup = uitabgroup(app.ControlPanel);
            app.RRPITab = uitab(app.ControlSetup);
            app.RRPITab.Title = 'RR-PI';
            app.RR_PI_Button = uibutton(app.RRPITab, 'push');
            app.RR_PI_Button.ButtonPushedFcn = createCallbackFcn(app, @RR_PI_ButtonPushed, true);
            app.RR_PI_Button.Text = 'Set Control Method';
            app.RR_PI_Heading = uilabel(app.RRPITab);
            app.RR_PI_Heading.Text = 'Redundancy Resolution using Pseutoinverse Method';
            app.RR_PI_Description = uilabel(app.RRPITab);
            app.RR_PI_Description.Text = 'Open-loop velocity-control of mobile-manipulator robot is achieved by taking pseudoinverse of the redundant Jacobian matrix, thereby resolving the underlying redundancy of the robot.';
            app.RRATS1Tab = uitab(app.ControlSetup);
            app.RRATS1Tab.Title = 'RR-ATS-1';
            app.RR_ATS1_Button = uibutton(app.RRATS1Tab, 'push');
            app.RR_ATS1_Button.ButtonPushedFcn = createCallbackFcn(app, @RR_ATS1_ButtonPushed, true);
            app.RR_ATS1_Button.Text = 'Set Control Method';
            app.RR_PI_Heading_2 = uilabel(app.RRATS1Tab);
            app.RR_PI_Heading_2.Text = 'Redundancy Resolution using Augmented Task-Space Method';
            app.RR_PI_Description_2 = uilabel(app.RRATS1Tab);
            app.RR_PI_Description_2.Text = 'Open-loop velocity-control of mobile-manipulator robot is achieved by augmenting the task-space of the robot with a constraint. This in turn increases the dimension of Jacobian matrix, making it invertible and thereby resolving the underlying redundancy of the robot. The constraint imposed in this case is:';
            app.dottheta_1dottheta_20Label = uilabel(app.RRATS1Tab);
            app.dottheta_1dottheta_20Label.Interpreter = 'latex';
            app.dottheta_1dottheta_20Label.Text = '$\dot{\theta_1}+\dot{\theta_2}=0$';
            app.RRATS2Tab = uitab(app.ControlSetup);
            app.RRATS2Tab.Title = 'RR-ATS-2';
            app.RR_ATS2_Button = uibutton(app.RRATS2Tab, 'push');
            app.RR_ATS2_Button.ButtonPushedFcn = createCallbackFcn(app, @RR_ATS2_ButtonPushed, true);
            app.RR_ATS2_Button.Text = 'Set Control Method';
            app.RR_PI_Heading_3 = uilabel(app.RRATS2Tab);
            app.RR_PI_Heading_3.Text = 'Redundancy Resolution using Augmented Task-Space Method';
            app.RR_PI_Description_3 = uilabel(app.RRATS2Tab);
            app.RR_PI_Description_3.Text = 'Open-loop velocity-control of mobile-manipulator robot is achieved by augmenting the task-space of the robot with a constraint. This in turn increases the dimension of Jacobian matrix, making it invertible and thereby resolving the underlying redundancy of the robot. The constraint imposed in this case is:';
            app.dottheta_10Label = uilabel(app.RRATS2Tab);
            app.dottheta_10Label.Interpreter = 'latex';
            app.dottheta_10Label.Text = '$\dot{\theta_1}=0$';
            app.RRAPTab = uitab(app.ControlSetup);
            app.RRAPTab.Title = 'RR-AP';
            app.RR_AP_Button = uibutton(app.RRAPTab, 'push');
            app.RR_AP_Button.ButtonPushedFcn = createCallbackFcn(app, @RR_AP_ButtonPushed, true);
            app.RR_AP_Button.Text = 'Set Control Method';
            app.RR_AP_Half_K3 = uieditfield(app.RRAPTab, 'numeric');
            app.RR_AP_Half_K3.Value = 0.66;
            app.K_1Label = uilabel(app.RRAPTab);
            app.K_1Label.Interpreter = 'latex';
            app.K_1Label.Text = '$K_1$';
            app.K_2Label = uilabel(app.RRAPTab);
            app.K_2Label.Interpreter = 'latex';
            app.K_2Label.Text = '$K_2$';
            app.RR_AP_Half_K2 = uieditfield(app.RRAPTab, 'numeric');
            app.RR_AP_Half_K2.Value = 0.25;
            app.RR_AP_Half_K1 = uieditfield(app.RRAPTab, 'numeric');
            app.RR_AP_Half_K1.Value = 1;
            app.betaLabel_3 = uilabel(app.RRAPTab);
            app.betaLabel_3.Interpreter = 'latex';
            app.betaLabel_3.Text = '$K_3$';
            app.RR_PI_Heading_4 = uilabel(app.RRAPTab);
            app.RR_PI_Heading_4.Text = 'Redundancy Resolution using Artificial Potential Method';
            app.RR_PI_Description_4 = uilabel(app.RRAPTab);
            app.RR_PI_Description_4.Text = 'Open-loop velocity-control of mobile-manipulator robot is achieved by applying an artificial potential to the configuration-space of the robot, thereby resolving the underlying redundancy. The artificial potential is of the form:';
            app.VK_1theta_12K_2theta_22K_3phi2Label = uilabel(app.RRAPTab);
            app.VK_1theta_12K_2theta_22K_3phi2Label.Interpreter = 'latex';
            app.VK_1theta_12K_2theta_22K_3phi2Label.Text = '$V=K_1 \theta_1^2+K_2 \theta_2^2+K_3 \phi^2$';
            app.CLRRMCCSTab = uitab(app.ControlSetup);
            app.CLRRMCCSTab.Title = 'CLRRMC-CS';
            app.CLRRMC_CS_Button = uibutton(app.CLRRMCCSTab, 'push');
            app.CLRRMC_CS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLRRMC_CS_ButtonPushed, true);
            app.CLRRMC_CS_Button.Text = 'Set Control Method';
            app.betaLabel_4 = uilabel(app.CLRRMCCSTab);
            app.betaLabel_4.Interpreter = 'latex';
            app.betaLabel_4.Text = '$\tau$ (s)';
            app.CLRRMC_CS_Tau = uieditfield(app.CLRRMCCSTab, 'numeric');
            app.CLRRMC_CS_Tau.Value = 3;
            app.deltaSliderLabel = uilabel(app.CLRRMCCSTab);
            app.deltaSliderLabel.Interpreter = 'latex';
            app.deltaSliderLabel.Text = '$\delta$';
            app.CLRRMC_CS_DeltaSlider = uislider(app.CLRRMCCSTab);
            app.CLRRMC_CS_DeltaSlider.Limits = [-1 1];
            app.CLRRMC_CS_DeltaSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.RR_PI_Heading_5 = uilabel(app.CLRRMCCSTab);
            app.RR_PI_Heading_5.Text = 'Closed-Loop Resolved-Rate Motion Control in Configuration-Space';
            app.RR_PI_Description_5 = uilabel(app.CLRRMCCSTab);
            app.RR_PI_Description_5.Text = 'Closed-loop resolved-rate motion control of mobile-manipulator robot is achieved by acting on the error between desired and actual configuration-space variables. The redundancy was resolved using task-space augmentation method:';
            app.dottheta_1dottheta_20Label_2 = uilabel(app.CLRRMCCSTab);
            app.dottheta_1dottheta_20Label_2.Interpreter = 'latex';
            app.dottheta_1dottheta_20Label_2.Text = '$\dot{\theta_1}+\dot{\theta_2}=0$';
            app.CLRRMCTSTab = uitab(app.ControlSetup);
            app.CLRRMCTSTab.Title = 'CLRRMC-TS';
            app.CLRRMC_TS_Button = uibutton(app.CLRRMCTSTab, 'push');
            app.CLRRMC_TS_Button.ButtonPushedFcn = createCallbackFcn(app, @CLRRMC_TS_ButtonPushed, true);
            app.CLRRMC_TS_Button.Text = 'Set Control Method';
            app.K_1Label_2 = uilabel(app.CLRRMCTSTab);
            app.K_1Label_2.Interpreter = 'latex';
            app.K_1Label_2.Text = '$K_1$';
            app.K_2Label_2 = uilabel(app.CLRRMCTSTab);
            app.K_2Label_2.Interpreter = 'latex';
            app.K_2Label_2.Text = '$K_2$';
            app.CLRRMC_TS_K2 = uieditfield(app.CLRRMCTSTab, 'numeric');
            app.CLRRMC_TS_K2.Value = 10;
            app.CLRRMC_TS_K1 = uieditfield(app.CLRRMCTSTab, 'numeric');
            app.CLRRMC_TS_K1.Value = 5;
            app.RR_PI_Heading_6 = uilabel(app.CLRRMCTSTab);
            app.RR_PI_Heading_6.Text = 'Closed-Loop Resolved-Rate Motion Control in Task-Space';
            app.RR_PI_Description_6 = uilabel(app.CLRRMCTSTab);
            app.RR_PI_Description_6.Text = 'Closed-loop resolved-rate motion control of mobile-manipulator robot is achieved by acting on the error between desired and actual task-space variables. The redundancy was resolved using task-space augmentation method:';
            app.dottheta_1dottheta_20Label_3 = uilabel(app.CLRRMCTSTab);
            app.dottheta_1dottheta_20Label_3.Interpreter = 'latex';
            app.dottheta_1dottheta_20Label_3.Text = '$\dot{\theta_1}+\dot{\theta_2}=0$';
            app.deltaSliderLabel_3 = uilabel(app.CLRRMCTSTab);
            app.deltaSliderLabel_3.Interpreter = 'latex';
            app.deltaSliderLabel_3.Text = '$\delta$';
            app.CLRRMC_TS_DeltaSlider = uislider(app.CLRRMCTSTab);
            app.CLRRMC_TS_DeltaSlider.Limits = [-1 1];
            app.CLRRMC_TS_DeltaSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.RobotSetup = uitabgroup(app.ControlPanel);
            app.RobotParametersTab = uitab(app.RobotSetup);
            app.RobotParametersTab.Title = 'Robot Parameters';
            app.RobotImage = uiimage(app.RobotParametersTab);
            app.RobotImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'Robot.png');
            app.LLabel = uilabel(app.RobotParametersTab);
            app.LLabel.Interpreter = 'latex';
            app.LLabel.Text = 'L (m)';
            app.WLabel = uilabel(app.RobotParametersTab);
            app.WLabel.Interpreter = 'latex';
            app.WLabel.Text = 'W (m)';
            app.L2_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.L2_RobotParam.Value = 1;
            app.L1_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.L1_RobotParam.Value = 2.5;
            app.w_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.w_RobotParam.Value = 0.2;
            app.r_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.r_RobotParam.Value = 0.25;
            app.L_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.L_RobotParam.Value = 1;
            app.W_RobotParam = uieditfield(app.RobotParametersTab, 'numeric');
            app.W_RobotParam.Value = 1;
            app.betaLabel_5 = uilabel(app.RobotParametersTab);
            app.betaLabel_5.Interpreter = 'latex';
            app.betaLabel_5.Text = 'r (m)';
            app.y_0Label_4 = uilabel(app.RobotParametersTab);
            app.y_0Label_4.Interpreter = 'latex';
            app.y_0Label_4.Text = '$L_2$ (m)';
            app.y_0Label_3 = uilabel(app.RobotParametersTab);
            app.y_0Label_3.Interpreter = 'latex';
            app.y_0Label_3.Text = '$L_1$ (m)';
            app.x_0Label_3 = uilabel(app.RobotParametersTab);
            app.x_0Label_3.Interpreter = 'latex';
            app.x_0Label_3.Text = 'w (m)';
            app.RobotParamsButton = uibutton(app.RobotParametersTab, 'push');
            app.RobotParamsButton.Text = 'Set Robot Parameters';
            app.TrajectorySetup = uitabgroup(app.ControlPanel);
            app.EllipticalTab = uitab(app.TrajectorySetup);
            app.EllipticalTab.Title = 'Elliptical';
            app.EllipseImage = uiimage(app.EllipticalTab);
            app.EllipseImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'Ellipse.png');
            app.EllipseButton = uibutton(app.EllipticalTab, 'push');
            app.EllipseButton.ButtonPushedFcn = createCallbackFcn(app, @EllipseButtonPushed, true);
            app.EllipseButton.Text = 'Set Trajectory Parameters';
            app.amLabel_2 = uilabel(app.EllipticalTab);
            app.amLabel_2.Interpreter = 'latex';
            app.amLabel_2.Text = 'a (m)';
            app.aEllipseEditField = uieditfield(app.EllipticalTab, 'numeric');
            app.aEllipseEditField.Value = 2.5;
            app.bEllipseEditField = uieditfield(app.EllipticalTab, 'numeric');
            app.bEllipseEditField.Value = 0.5;
            app.bmLabel_2 = uilabel(app.EllipticalTab);
            app.bmLabel_2.Interpreter = 'latex';
            app.bmLabel_2.Text = 'b (m)';
            app.betaEllipseEditField = uieditfield(app.EllipticalTab, 'numeric');
            app.betaEllipseEditField.Value = 30;
            app.betaLabel = uilabel(app.EllipticalTab);
            app.betaLabel.Interpreter = 'latex';
            app.betaLabel.Text = '$\beta$ (deg)';
            app.x_0Label_2 = uilabel(app.EllipticalTab);
            app.x_0Label_2.Interpreter = 'latex';
            app.x_0Label_2.Text = '$x_0$ (m)';
            app.x0EllipseEditField = uieditfield(app.EllipticalTab, 'numeric');
            app.x0EllipseEditField.Value = 0.5;
            app.y_0Label_2 = uilabel(app.EllipticalTab);
            app.y_0Label_2.Interpreter = 'latex';
            app.y_0Label_2.Text = '$y_0$ (m)';
            app.y0EllipseEditField = uieditfield(app.EllipticalTab, 'numeric');
            app.y0EllipseEditField.Value = 0.5;
            app.TimeLabel = uilabel(app.EllipticalTab);
            app.TimeLabel.Text = 'Time (s)';
            app.TimeEllipseKnob = uiknob(app.EllipticalTab, 'continuous');
            app.TimeEllipseKnob.Limits = [0 60];
            app.TimeEllipseKnob.Value = 20;
            app.LoopsLabel = uilabel(app.EllipticalTab);
            app.LoopsLabel.Text = 'Loops (n)';
            app.LoopEllipseKnob = uiknob(app.EllipticalTab, 'continuous');
            app.LoopEllipseKnob.Limits = [0 10];
            app.LoopEllipseKnob.Value = 3;
            app.RectangularTab = uitab(app.TrajectorySetup);
            app.RectangularTab.Title = 'Rectangular';
            app.RectangleImage = uiimage(app.RectangularTab);
            app.RectangleImage.ImageSource = fullfile(pathToMLAPP, 'Images', 'Rectangle.png');
            app.RectangleButton = uibutton(app.RectangularTab, 'push');
            app.RectangleButton.ButtonPushedFcn = createCallbackFcn(app, @RectangleButtonPushed, true);
            app.RectangleButton.Text = 'Set Trajectory Parameters';
            app.amLabel = uilabel(app.RectangularTab);
            app.amLabel.Interpreter = 'latex';
            app.amLabel.Text = 'a (m)';
            app.aRectangleEditField = uieditfield(app.RectangularTab, 'numeric');
            app.aRectangleEditField.Value = 3.5;
            app.bmLabel = uilabel(app.RectangularTab);
            app.bmLabel.Interpreter = 'latex';
            app.bmLabel.Text = 'b (m)';
            app.bRectangleEditField = uieditfield(app.RectangularTab, 'numeric');
            app.bRectangleEditField.Value = 3.5;
            app.betaLabel_2 = uilabel(app.RectangularTab);
            app.betaLabel_2.Interpreter = 'latex';
            app.betaLabel_2.Text = '$\beta$ (deg)';
            app.betaRectangleEditField = uieditfield(app.RectangularTab, 'numeric');
            app.x_0Label = uilabel(app.RectangularTab);
            app.x_0Label.Interpreter = 'latex';
            app.x_0Label.Text = '$x_0$ (m)';
            app.x0RectangleEditField = uieditfield(app.RectangularTab, 'numeric');
            app.x0RectangleEditField.Value = 1;
            app.y_0Label = uilabel(app.RectangularTab);
            app.y_0Label.Interpreter = 'latex';
            app.y_0Label.Text = '$y_0$ (m)';
            app.y0RectangleEditField = uieditfield(app.RectangularTab, 'numeric');
            app.y0RectangleEditField.Value = 1;
            app.TimeLabel_2 = uilabel(app.RectangularTab);
            app.TimeLabel_2.Text = 'Time (s)';
            app.TimeRectangleKnob = uiknob(app.RectangularTab, 'continuous');
            app.TimeRectangleKnob.Limits = [0 60];
            app.TimeRectangleKnob.Value = 20;
            app.LoopsLabel_2 = uilabel(app.RectangularTab);
            app.LoopsLabel_2.Text = 'Loops (n)';
            app.LoopRectangleKnob = uiknob(app.RectangularTab, 'continuous');
            app.LoopRectangleKnob.Limits = [0 10];
            app.LoopRectangleKnob.Value = 3;
            app.TrajectoryConfigurationLabel = uilabel(app.ControlPanel);
            app.TrajectoryConfigurationLabel.Text = 'Trajectory Configuration';
            app.RobotConfigurationLabel = uilabel(app.ControlPanel);
            app.RobotConfigurationLabel.Text = 'Robot Configuration';
            app.STOPButton = uibutton(app.ControlPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RUNButton = uibutton(app.ControlPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ControlConfigurationLabel = uilabel(app.ControlPanel);
            app.ControlConfigurationLabel.Text = 'Control Configuration';
            app.TrajectoryTrackingErrorLabel = uilabel(app.ControlPanel);
            app.TrajectoryTrackingErrorLabel.Text = 'Trajectory Tracking Error';
            app.Label = uilabel(app.ControlPanel);
            app.Label.Text = 'The trajectory tracking error is computed by analyzing the individual errors in X and Y coordinates. The cumulative error is reported in terms of the root-mean-squared (RMS) value, which is computed over the trajectory traversed by the robot. The RMSE error is given by:';
            app.e_RMSsqrtfracsum_i1nx_dx2y_dy2nLabel = uilabel(app.ControlPanel);
            app.e_RMSsqrtfracsum_i1nx_dx2y_dy2nLabel.Interpreter = 'latex';
            app.e_RMSsqrtfracsum_i1nx_dx2y_dy2nLabel.Text = '$e_{RMS}=\sqrt{\frac{\sum_{i=1}^{n}(x_d-x)^2+(y_d-y)^2}{n}}$';
            app.MobileManipulatorRobotSimulatorLabel = uilabel(app.MainGridLayout);
            app.MobileManipulatorRobotSimulatorLabel.Text = 'Mobile Manipulator Robot Simulator';
            app.DevelopersTanmaySamakandChinmaySamakLabel = uilabel(app.MainGridLayout);
            app.DevelopersTanmaySamakandChinmaySamakLabel.Text = 'Developers: Tanmay Samak and Chinmay Samak';
            % Show the figure after all components are created
            app.MobileManipulatorRobotSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mobile_Manipulator_Robot_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MobileManipulatorRobotSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MobileManipulatorRobotSimulatorUIFigure)
        end
    end
end